classdef signal_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HzLabel                   matlab.ui.control.Label
        FunctionButtonGroup       matlab.ui.container.ButtonGroup
        CosButton                 matlab.ui.control.RadioButton
        SinButton                 matlab.ui.control.RadioButton
        NumPeriodsEditField       matlab.ui.control.NumericEditField
        NumPeriodsEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        NoiseEditField            matlab.ui.control.NumericEditField
        NoiseEditFieldLabel       matlab.ui.control.Label
        ActivateNoiseCheckBox     matlab.ui.control.CheckBox
        CloseButton               matlab.ui.control.Button
        SaveFileButton            matlab.ui.control.Button
        DrawButton                matlab.ui.control.Button
        SignalGeneratorLabel      matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        En = false % the flag that will be true when activate noise check box is filled
        signal % the signal that is created at the end (whether with noise or not)
        t % the time value (the values of the x axis)
        nfValue %the value of noise field ()
        amplitude % the value of Amplitude field
        frequency % the value of frequency field
        num_of_periods % the value of Numperiods field
        En_valuechanged = true % the flag that signals the correctness of values
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ValueChanged(app)
            %this function checks whether or not the values that is enters
            %is valid(greater than or equal to 0). upon finding an error it
            %will display an appropriate message and the value of that
            %field is set to 0.
            app.amplitude = app.AmplitudeEditField.Value;
            app.frequency = app.FrequencyEditField.Value;
            app.num_of_periods = app.NumPeriodsEditField.Value;
            app.nfValue = app.NoiseEditField.Value;
            app.En_valuechanged = true;
            if app.amplitude < 0
                msgbox('The amplitude should not be less than zero.');
                app.AmplitudeEditField.Value = 0;
                app.En_valuechanged = false;
            elseif app.frequency < 0
                   msgbox('The frequency should not be less than zero.');
                   app.FrequencyEditField.Value = 0;
                   app.En_valuechanged = false;
            elseif app.num_of_periods < 0
                msgbox('The number of periods should not be less than zero.');
               app.NumPeriodsEditField.Value = 0;
               app.En_valuechanged = false;
            elseif app.nfValue < 0
                msgbox('The Noise should not be less than zero.');
               app.NoiseEditField.Value = 0;
               app.En_valuechanged = false;
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
           %the function of the close button
            closereq();
        end

        % Value changed function: ActivateNoiseCheckBox
        function ActivateNoiseCheckBoxValueChanged(app, event)
            %This function gets the value of noise checkbox and activates and deactivates the
            %noiseEditfield and its label.
           
            if not(app.ActivateNoiseCheckBox.Value ) 
                set(app.NoiseEditField,'Enable','off');
                set(app.NoiseEditFieldLabel,'Enable','off');
                app.En = false;
            else
                 set(app.NoiseEditField,'Enable','on');
                 set(app.NoiseEditFieldLabel,'Enable','on');
                 app.En = true;

            end
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            %here the values are fetched to create the selected(sin or cos)
            %function with the appropriate noise

            %first the values are checked
           ValueChanged(app);
if app.En_valuechanged
           %then it is checked which function is chosen sine or cosine
            if app.SinButton.Value
                %label of the Y-axis changes accroding to the function that
                %is chosen
                set(app.UIAxes.YLabel,'string','sin(t)')
                %the time is divided into 100 units
                app.t = 0:1/(app.frequency*100):(1/app.frequency) * app.num_of_periods;
                %y = A * sin(wt) -- w = 2 *pi*f 
                f = app.amplitude * sin(2 * pi * app.frequency * app.t);
                %it checkes whether or not it should apply any noise on the
                %signal
                if not(app.En)
                    %if the noise is not activated
                    app.signal = f;
                    plot(app.UIAxes,app.t,app.signal);  
                    %after plotting the save button is activated
                    set(app.SaveFileButton,'Enable','on');
                else
                    
                    %if noise is activated
                    %the value of noise is a percentage of the amplitude
                    noise_value = (app.nfValue/100) * app.amplitude;
                    noise = rand(1,length(app.t))*2*noise_value - noise_value; 
                    app.signal = f + noise;
                    plot(app.UIAxes,app.t,app.signal);
                    
                    set(app.SaveFileButton,'Enable','on');
                end
            else
                %if cosine is chosen 
                 %label of the Y-axis changes accroding to the function that
                %is chosen
                set(app.UIAxes.YLabel,'string','cos(t)')

                     app.t = 0:1/(app.frequency*100): (1/app.frequency) * app.num_of_periods;
                      %y = A * cos(wt) -- w = 2 *pi*f
                     f = app.amplitude * cos(2 * pi * app.frequency * app.t);
                if not(app.En)
                    %if noise is not activated
                    app.signal = f;
                        plot(app.UIAxes,app.t,app.signal);  
                         set(app.SaveFileButton,'Enable','on');
                else
                    %if noise is activated
                      noise_value = (app.nfValue/100) * app.amplitude;
                    noise = rand(1,length(app.t))*2*noise_value - noise_value; 
                    app.signal = f + noise;
                    plot(app.UIAxes,app.t,app.signal);  
                     set(app.SaveFileButton,'Enable','on');
                end
            end
end
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            
            wrmatrix = [app.t(:),app.signal(:)];
            [file,path] = uiputfile('*.csv');
            if (file ~= 0)
                %this if statement ensures that no error stops the program
                %from running
             filename = fullfile(path,file);
             writematrix(wrmatrix,filename,'Delimiter',',') %since the delimiter was not specified ',' is chosen
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Diagram')
            xlabel(app.UIAxes, 'time')
            zlabel(app.UIAxes, 'Z')
            app.SignalGeneratorLabel = uilabel(app.UIFigure);
            app.SignalGeneratorLabel.Text = 'Signal Generator';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.SaveFileButton = uibutton(app.UIFigure, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Enable = 'off';
            app.SaveFileButton.Text = 'Save File ';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ActivateNoiseCheckBox = uicheckbox(app.UIFigure);
            app.ActivateNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivateNoiseCheckBoxValueChanged, true);
            app.ActivateNoiseCheckBox.Text = 'Activate Noise';
            app.NoiseEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseEditFieldLabel.Enable = 'off';
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseEditField.Enable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumPeriodsEditFieldLabel = uilabel(app.UIFigure);
            app.NumPeriodsEditFieldLabel.Text = 'Num. Periods';
            app.NumPeriodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctionButtonGroup.Title = 'Function';
            app.SinButton = uiradiobutton(app.FunctionButtonGroup);
            app.SinButton.Text = 'Sin(...)';
            app.SinButton.Value = true;
            app.CosButton = uiradiobutton(app.FunctionButtonGroup);
            app.CosButton.Text = 'Cos(...)';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @ValueChanged)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end